classdef contrast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ContrastSlider       matlab.ui.control.Slider
        ContrastSliderLabel  matlab.ui.control.Label
        SaveButton           matlab.ui.control.Button
        Image                matlab.ui.control.Image
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function contrastedImage = contrastImage(app, contrastMultiplier)
            if isempty(app.Image.ImageSource)
                app.Image.ImageSource = 'sample.jpg';
            end

            % Load the original image data
            imageData = imread(app.Image.ImageSource);
            
            % Turn to hsv
            hsvImage = rgb2hsv(imageData);

            v = hsvImage(:, :, 3);

            %Value Formula
            V = 0.5 + contrastMultiplier * (v - 0.5);
            
            %Cannot Exceed 1, or below 0
            if V > 1
                V = 1;
            elseif V < 0
                V = 0;
            end

            %Apply new Value
            hsvImage(:,:,3) = V;

            % Convert HSV back to RGB
            contrastedImage = hsv2rgb(hsvImage);

            % Display rotated image
            imshow(contrastedImage, 'Parent', app.UIAxes);
            app.UIAxes.UserData.ImageSource = contrastedImage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            contrastMultiplier = event.Value;
            app.contrastImage(contrastMultiplier)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                if isfield(app.UIAxes.UserData, 'ContrastedImage')
                    imageToSave = app.UIAxes.UserData.constrastedImage;
                else
                    imageToSave = app.UIAxes.UserData.ImageSource;
                end
                
                % Save the image
                [file, path] = uiputfile({'*.jpg'; '*.png'; '*.tif'}, 'Save Image As');
                if ischar(file)
                    imwrite(imageToSave, fullfile(path, file));
                end
            else
                uialert(app.UIFigure, 'No image to save.', 'Save Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Contrast')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 2];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contrast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end