classdef presets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SaveButton       matlab.ui.control.Button
        Image            matlab.ui.control.Image
        CinematicButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CinematicButton
        function CinematicButtonPushed(app, event)
        % Get the current image
            img = app.Image.ImageSource;
            
            % Read the image if it's a filename, otherwise use as is
            if ischar(img)
                img = imread(img);
            end
            
            % Convert to double for calculations
            img = im2double(img);
            
            % Adjust contrast and decrease dark spots
            img = imadjust(img, [0.2 0.8], [0 1], 0.8);
            
            % Slightly increase blue
            img(:,:,3) = img(:,:,3) * 1.1;  % Increase blue by 10%
            
            % Ensure values are in valid range
            img = min(max(img, 0), 1);
            
            % Display the modified image in the UIAxes
            imshow(img, 'Parent', app.UIAxes);
            
            % Update axis labels and title
            title(app.UIAxes, 'Cinematic Effect');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Get the current image from UIAxes
            ax_image = getimage(app.UIAxes);
            
            if ~isempty(ax_image)
                % Open a save dialog
                [filename, pathname] = uiputfile({'*.png','PNG Image (*.png)';...
                                                  '*.jpg','JPEG Image (*.jpg)';...
                                                  '*.tif','TIFF Image (*.tif)'},...
                                                  'Save Image As');
                
                if filename ~= 0  % Check if user didn't cancel the dialog
                    fullpath = fullfile(pathname, filename);
                    
                    % Save the image
                    imwrite(ax_image, fullpath);
                    
                    % Inform the user
                    msgbox('Image saved successfully!', 'Save Complete');
                end
            else
                % If no image in UIAxes, inform the user
                warndlg('No image to save. Please apply a filter first.', 'Warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CinematicButton = uibutton(app.UIFigure, 'push');
            app.CinematicButton.ButtonPushedFcn = createCallbackFcn(app, @CinematicButtonPushed, true);
            app.CinematicButton.Text = 'Cinematic';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sample.jpg');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = presets

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end